classdef ComfortTesting_Pulse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow  matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        PlayAV      matlab.ui.control.Button
        STOPButton  matlab.ui.control.Button
        info        matlab.ui.control.Label
    end

    properties (Access = public)
        CallingApp
        Source_Signal
        Go %whether user wants to play 40Hz AV (i.e. did user press on start)?
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PulseEvokedPotentialsTask)
            app.CallingApp=PulseEvokedPotentialsTask;
            
            %adjust gui position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening comfortTesting sub-app'); %indicate in log file that we are testing for comfort
            call_to_NIDAQ(app.CallingApp,'startComfortTest'); %indicate in recording that we are starting comfortTesting
            
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", testing for comfort"); %label information about this sub-app
            
            one_sec_signal=producePulse(app.CallingApp.PulseDuration,app.CallingApp.SoundFreq,app.CallingApp.NIDAQSampleRate);
            app.Source_Signal=produceMultiplePulses('safety_testing',one_sec_signal,app.CallingApp.InterTrialInterval,app.CallingApp.NumberOfTrials_perCondition,app.CallingApp.NIDAQSampleRate);
            
            app.PlayAV.Enable=1; %now that we have set up everything, enable the PLAY button
        end

        % Button pushed function: PlayAV
        function PlayAVPushed(app, event)
            app.STOPButton.Enable=1; %if pressed PLAY button, unable the STOP button
            app.PlayAV.Enable=0; %...disable the PLAY button
            app.Go=1; %save in properties that the user has pressed the PLAY button (and not pressed the STOP button)
            
            while app.Go %while property Go is still true, keep playing the signal
                call_to_NIDAQ(app.CallingApp,'AV',app.Source_Signal.AV{1});
            end
     
            app.PlayAV.Enable=1; %if Go is no longer true, enable the PLAY button again
            app.STOPButton.Enable=0; %... and disable the STOP button
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.Go=0; %save in properties that user has pressed the STOP button
            call_to_NIDAQ(app.CallingApp,'stop'); %if press on STOP button, stop the signal from playing
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            exitSubApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Comfort Testing';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.PlayAV = uibutton(app.GridLayout, 'push');
            app.PlayAV.ButtonPushedFcn = createCallbackFcn(app, @PlayAVPushed, true);
            app.PlayAV.Enable = 'off';
            app.PlayAV.Text = 'Play AV';
            app.STOPButton = uibutton(app.GridLayout, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComfortTesting_Pulse(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end