classdef Measure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Play40hzAV_Button              matlab.ui.control.Button
        info                           matlab.ui.control.Label
        FlickerBrightness1inluxLabel   matlab.ui.control.Label
        FlickerBrightness1inlux_EditField  matlab.ui.control.NumericEditField
        FlickerBrightness2inluxLabel   matlab.ui.control.Label
        FlickerBrightness2inlux_EditField  matlab.ui.control.NumericEditField
        FlickerVolume1indBALabel       matlab.ui.control.Label
        FlickerVolume1indB_EditField   matlab.ui.control.NumericEditField
        FlickerVolume2indBALabel       matlab.ui.control.Label
        FlickerVolume2indB_EditField   matlab.ui.control.NumericEditField
        DonemeasuringButton            matlab.ui.control.Button
        PlayconstantAVButton           matlab.ui.control.Button
        ConstantBrightness1inluxLabel  matlab.ui.control.Label
        Brightness1inlux_EditField     matlab.ui.control.NumericEditField
        ConstantBrightness2inluxLabel  matlab.ui.control.Label
        Brightness2inlux_EditField     matlab.ui.control.NumericEditField
        ConstantVolume1indBALabel      matlab.ui.control.Label
        Volume1indB_EditField          matlab.ui.control.NumericEditField
        ConstantVolume2indBALabel      matlab.ui.control.Label
        Volume2indB_EditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        CallingApp
        Source_Signal
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask)
            app.CallingApp=FlickerMasterTask;
            
            %adjust window position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening Measure sub-app.'); %indicate in log file that we are measuring brightness and volume
            call_to_NIDAQ(app.CallingApp,'startMeasure');
            
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", measure brightness and volume"); %label information about this app
            
            app.Source_Signal=produceExperimentSignals('safety_testing',app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate);
            
            %once everything is set up, can enable functionality:
            app.Play40hzAV_Button.Enable=1; %first, measure with 40Hz-AV
        end

        % Button pushed function: Play40hzAV_Button
        function Play40hzAV_ButtonPushed(app, event)
            app.Play40hzAV_Button.Enable=0;
            app.PlayconstantAVButton.Enable=0;
            app.DonemeasuringButton.Enable=0;
            app.FlickerBrightness1inlux_EditField.Enable=1;
            app.FlickerBrightness2inlux_EditField.Enable=1;
            app.FlickerVolume1indB_EditField.Enable=1;
            app.FlickerVolume2indB_EditField.Enable=1;

            call_to_NIDAQ(app.CallingApp,'40Hz-AV',app.Source_Signal.Hz40);
            
            app.Play40hzAV_Button.Enable=1;
            app.PlayconstantAVButton.Enable=1; %can now also activate the Play constant-AV button
            app.DonemeasuringButton.Enable=1;
        end

        % Button pushed function: PlayconstantAVButton
        function PlayconstantAVButtonPushed(app, event)
            app.PlayconstantAVButton.Enable=0;
            app.Play40hzAV_Button.Enable=0;
            app.DonemeasuringButton.Enable=0;
            app.Brightness1inlux_EditField.Enable=1;
            app.Brightness2inlux_EditField.Enable=1;
            app.Volume1indB_EditField.Enable=1;
            app.Volume2indB_EditField.Enable=1;
            
            constant_signal=[repmat(5,app.CallingApp.NIDAQSampleRate*10,1) generate_sound(7000,app.CallingApp.NIDAQSampleRate,10)']; %generate 10s of constant signal
            
            call_to_NIDAQ(app.CallingApp,'constant-AV',constant_signal);
            
            app.Play40hzAV_Button.Enable=1;
            app.PlayconstantAVButton.Enable=1;
            app.DonemeasuringButton.Enable=1;
        end

        % Button pushed function: DonemeasuringButton
        function DonemeasuringButtonPushed(app, event)
            app.DonemeasuringButton.Enable=0;
            app.Play40hzAV_Button.Enable=0;
            app.PlayconstantAVButton.Enable=0;
            
            confirmAnswer = uiconfirm(app.MainWindow,'Are you sure you are done measuring brightness and volume?','Confirm','Options',{'Yes','No'});
            if strcmp(confirmAnswer,'Yes')
                
                %turn off all user functionalities:
                app.FlickerBrightness1inlux_EditField.Enable=0;
                app.FlickerBrightness2inlux_EditField.Enable=0;
                app.FlickerVolume1indB_EditField.Enable=0;
                app.FlickerVolume2indB_EditField.Enable=0;
                app.Brightness1inlux_EditField.Enable=0;
                app.Brightness2inlux_EditField.Enable=0;
                app.Volume1indB_EditField.Enable=0;
                app.Volume2indB_EditField.Enable=0;
                
                %add additional information to the structure in the mat file we
                %saved:
                temp=app.CallingApp.MatFileObj.ExpDetails; %fetch the structure
                temp.Flicker40Hz_BrightnessMeasured=[app.FlickerBrightness1inlux_EditField.Value app.FlickerBrightness2inlux_EditField.Value];
                temp.Flicker40Hz_VolumeMeasured=[app.FlickerVolume1indB_EditField.Value app.FlickerVolume2indB_EditField.Value];
                temp.constant_BrightnessMeasured=[app.Brightness1inlux_EditField.Value app.Brightness2inlux_EditField.Value];
                temp.constant_VolumeMeasured=[app.Volume1indB_EditField.Value app.Volume2indB_EditField.Value];
                app.CallingApp.MatFileObj.ExpDetails=temp; %replace the structure in mat file with appended structure
                
                %add same data to log file:
                fprintf(app.CallingApp.FileID,'\n%s\t%s\n%s\t%s\n%s\t%s\n%s\t%s','40Hz Flicker Brightness 1 (in lux)',string(app.FlickerBrightness1inlux_EditField.Value),'40Hz Flicker Brighness 2 (in lux)',string(app.FlickerBrightness2inlux_EditField.Value),'40Hz Flicker Volume 1 (in dBA)',string(app.FlickerVolume1indB_EditField.Value),'40Hz Flicker Volume 2 (in dBA)',string(app.FlickerVolume2indB_EditField.Value));
                fprintf(app.CallingApp.FileID,'\n%s\t%s\n%s\t%s\n%s\t%s\n%s\t%s','Constant Brightness 1 (in lux)',string(app.Brightness1inlux_EditField.Value),'Constant Brighness 2 (in lux)',string(app.Brightness2inlux_EditField.Value),'Constant Volume 1 (in dBA)',string(app.Volume1indB_EditField.Value),'Constant Volume 2 (in dBA)',string(app.Volume2indB_EditField.Value));
                
                call_to_NIDAQ(app.CallingApp,'end'); %indicate in recording that we finished running this sub-app
                fprintf(app.CallingApp.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'Exiting from sub-app');
                changeMainAppState(app.CallingApp,1);
                delete(app) %WAY TO CLEANLY STOP ALL PROCESSES AND EXIT APP?
            else
                app.DonemeasuringButton.Enable=1;
                app.Play40hzAV_Button.Enable=0;
                app.PlayconstantAVButton.Enable=0;
            end
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            DonemeasuringButtonPushed(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Measure';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.Play40hzAV_Button = uibutton(app.GridLayout, 'push');
            app.Play40hzAV_Button.ButtonPushedFcn = createCallbackFcn(app, @Play40hzAV_ButtonPushed, true);
            app.Play40hzAV_Button.Enable = 'off';
            app.Play40hzAV_Button.Text = 'Play 40Hz AV';
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            app.FlickerBrightness1inluxLabel = uilabel(app.GridLayout);
            app.FlickerBrightness1inluxLabel.Text = 'Flicker Brightness 1 (in lux):';
            app.FlickerBrightness1inlux_EditField = uieditfield(app.GridLayout, 'numeric');
            app.FlickerBrightness1inlux_EditField.Enable = 'off';
            app.FlickerBrightness2inluxLabel = uilabel(app.GridLayout);
            app.FlickerBrightness2inluxLabel.Text = 'Flicker Brightness 2 (in lux):';
            app.FlickerBrightness2inlux_EditField = uieditfield(app.GridLayout, 'numeric');
            app.FlickerBrightness2inlux_EditField.Enable = 'off';
            app.FlickerVolume1indBALabel = uilabel(app.GridLayout);
            app.FlickerVolume1indBALabel.Text = 'Flicker Volume 1 (in dBA):';
            app.FlickerVolume1indB_EditField = uieditfield(app.GridLayout, 'numeric');
            app.FlickerVolume1indB_EditField.Enable = 'off';
            app.FlickerVolume2indBALabel = uilabel(app.GridLayout);
            app.FlickerVolume2indBALabel.Text = 'Flicker Volume 2 (in dBA):';
            app.FlickerVolume2indB_EditField = uieditfield(app.GridLayout, 'numeric');
            app.FlickerVolume2indB_EditField.Enable = 'off';
            app.DonemeasuringButton = uibutton(app.GridLayout, 'push');
            app.DonemeasuringButton.ButtonPushedFcn = createCallbackFcn(app, @DonemeasuringButtonPushed, true);
            app.DonemeasuringButton.Enable = 'off';
            app.DonemeasuringButton.Text = 'Done measuring';
            app.PlayconstantAVButton = uibutton(app.GridLayout, 'push');
            app.PlayconstantAVButton.ButtonPushedFcn = createCallbackFcn(app, @PlayconstantAVButtonPushed, true);
            app.PlayconstantAVButton.Enable = 'off';
            app.PlayconstantAVButton.Text = 'Play constant AV';
            app.ConstantBrightness1inluxLabel = uilabel(app.GridLayout);
            app.ConstantBrightness1inluxLabel.Text = 'Constant Brightness 1 (in lux):';
            app.Brightness1inlux_EditField = uieditfield(app.GridLayout, 'numeric');
            app.Brightness1inlux_EditField.Enable = 'off';
            app.ConstantBrightness2inluxLabel = uilabel(app.GridLayout);
            app.ConstantBrightness2inluxLabel.Text = 'Constant Brightness 2 (in lux):';
            app.Brightness2inlux_EditField = uieditfield(app.GridLayout, 'numeric');
            app.Brightness2inlux_EditField.Enable = 'off';
            app.ConstantVolume1indBALabel = uilabel(app.GridLayout);
            app.ConstantVolume1indBALabel.Text = 'Constant Volume 1 (in dBA):';
            app.Volume1indB_EditField = uieditfield(app.GridLayout, 'numeric');
            app.Volume1indB_EditField.Enable = 'off';
            app.ConstantVolume2indBALabel = uilabel(app.GridLayout);
            app.ConstantVolume2indBALabel.Text = 'Constant Volume 2 (in dBA):';
            app.Volume2indB_EditField = uieditfield(app.GridLayout, 'numeric');
            app.Volume2indB_EditField.Enable = 'off';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Measure(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end