classdef Occluded_Pulse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                    matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        info                          matlab.ui.control.Label
        RunOccludedCondition_Button   matlab.ui.control.Button
        StopOccludedCondition_Button  matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp
        Source_Signal
        OccludedTask_Paused
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask)
            app.CallingApp=FlickerMasterTask;
            
            %adjust gui position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening Occluded sub-app'); %indicate in log file that we are testing for comfort
            call_to_NIDAQ(app.CallingApp,'startOccluded'); %indicate in recording that we are starting the occluded task
            
            one_sec_signal=producePulse(app.CallingApp.PulseDuration,app.CallingApp.SoundFreq,app.CallingApp.NIDAQSampleRate);
            app.Source_Signal=produceMultiplePulses('safety_testing',one_sec_signal,app.CallingApp.InterTrialInterval,app.CallingApp.NumberOfTrials_perCondition,app.CallingApp.NIDAQSampleRate);
            
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", occluded condition"); %label information about this sub-app
            
            %once everything set up, can enable functionality:
            app.RunOccludedCondition_Button.Enable=1;
        end

        % Button pushed function: RunOccludedCondition_Button
        function RunOccludedCondition_ButtonPushed(app, event)
            app.RunOccludedCondition_Button.Enable=0;
            app.StopOccludedCondition_Button.Enable=1;
            app.OccludedTask_Paused=0; %re-initialize
            
            %play 200 trials of AV pulse
            for j=1:20
                for i=1:10
                    if ~app.OccludedTask_Paused %if task was not paused, play trial
                        call_to_NIDAQ(app.CallingApp,'AV',app.Source_Signal.AV{i});
                    elseif app.OccludedTask_Paused
                        break;
                    end
                end
            end
            
            if ~app.OccludedTask_Paused %means we finished the task
                fprintf(app.CallingApp.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'Completed task');
                app.RunOccludedCondition_Button.Enable=0;
            else
                app.RunOccludedCondition_Button.Enable=1;
            end
            
            app.StopOccludedCondition_Button.Enable=0;
        end

        % Button pushed function: StopOccludedCondition_Button
        function StopOccludedCondition_ButtonPushed(app, event)
            app.OccludedTask_Paused=1;
            call_to_NIDAQ(app.CallingApp,'stop');
            fprintf(app.CallingApp.FileID,'\n%s\t%s',strjoin(string(round(clock)),'-'),'Occluded condition stopped');
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            exitSubApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Occluded Condition';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            app.RunOccludedCondition_Button = uibutton(app.GridLayout, 'push');
            app.RunOccludedCondition_Button.ButtonPushedFcn = createCallbackFcn(app, @RunOccludedCondition_ButtonPushed, true);
            app.RunOccludedCondition_Button.Enable = 'off';
            app.RunOccludedCondition_Button.Text = {'Run Occluded '; 'Condition'};
            app.StopOccludedCondition_Button = uibutton(app.GridLayout, 'push');
            app.StopOccludedCondition_Button.ButtonPushedFcn = createCallbackFcn(app, @StopOccludedCondition_ButtonPushed, true);
            app.StopOccludedCondition_Button.Enable = 'off';
            app.StopOccludedCondition_Button.Text = {'Stop Occluded '; 'Condition'};
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Occluded_Pulse(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end