classdef RunTask_FlickerDuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        info                         matlab.ui.control.Label
        CurrentstimulusLabel         matlab.ui.control.Label
        CurrentStimIndicator         matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        MinutessentLabel             matlab.ui.control.Label
        MinutesSentValue             matlab.ui.control.Label
        TimestartedLabel             matlab.ui.control.Label
        TimeStartedValue             matlab.ui.control.Label
        STARTButton                  matlab.ui.control.Button
        PAUSEButton                  matlab.ui.control.Button
        LengthofstimulationminLabel  matlab.ui.control.Label
        LengthofstimulationminEditField  matlab.ui.control.NumericEditField
        ConditionDropDownLabel       matlab.ui.control.Label
        ConditionDropDown            matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        CallingApp
        Condition %which condition we are playing
        TotalNumberOfMinutes=60 %how long will flicker be administered for, total (in min)
        TimeStarted %time started a period of stimulation
        MinutesLeft %minutes of stimulation left
        Source_Signal %the 1min worth of signal that will be played (repetitively to last 1h)
        Task_Paused=0 %to keep track of whether task was paused
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask)
            app.CallingApp=FlickerMasterTask; %define the calling app
            
            %adjust window position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening RunTask sub-app'); %indicate in log file that we are starting the RunTask
            call_to_NIDAQ(app.CallingApp,'startRunTask'); %indicate in recording that we are starting the runTask
            
            app.MinutesLeft=app.TotalNumberOfMinutes;
            
            %set some labels
            app.LengthofstimulationminEditField.Value=app.TotalNumberOfMinutes;
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", condition _________, ",num2str(app.TotalNumberOfMinutes)," minutes");
            
            %enable buttons:
            app.LengthofstimulationminEditField.Enable=1;
            app.ConditionDropDown.Enable=1;
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            app.ConditionDropDown.Enable=0;
            pause(0.5); %so the dropdown menu is actually disabled
            app.LengthofstimulationminEditField.Enable=0; %prevent user from changing the length of stimulation
            value = app.ConditionDropDown.Value;
            if ~strcmp(value,'select') %if user selected a condition
                app.Condition=value;
                
                %produce signal that will be played:
                if strcmp(app.Condition,'40Hz-AV')
                    app.Source_Signal=produceSignals(60,40,0,app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate); %create 1min 40Hz AV signal
                elseif strcmp(app.Condition,'5.5Hz-AV')
                    app.Source_Signal=produceSignals(60,5.5,0,app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate); %create 1min 40Hz AV signal
                elseif strcmp(app.Condition,'R-AV')
                    app.Source_Signal=produceSignals(60,40,1,app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate); %create 1min 40Hz AV signal
                end
                
                app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", condition",app.Condition,", ",num2str(app.TotalNumberOfMinutes)," minutes");
                
                %add additional information, including Source_Signal, to the structure in the mat file we saved
                temp=app.CallingApp.MatFileObj.ExpDetails; %fetch the structure
                temp.Condition=app.Condition;
                temp.NumberMinutes=app.TotalNumberOfMinutes;
                temp.Source_Signal=app.Source_Signal; %add the Source_Signal
                app.CallingApp.MatFileObj.ExpDetails=temp; %replace the structure in mat file with appended structure
                
                 %write begining of task info in log file:
                fprintf(app.CallingApp.FileID,'\n%s\t%s','Condition:',app.Condition);
                fprintf(app.CallingApp.FileID,'\n%s\t%s','Duration (min):',num2str(app.TotalNumberOfMinutes));
                fprintf(app.CallingApp.FileID,'\n%s\t%s\t%s','Time','Comments'); %keep track of comments
                
                app.STARTButton.Enable=1;
            else
                app.ConditionDropDown.Enable=1;
                app.LengthofstimulationminEditField.Enable=1;
            end
        end

        % Value changed function: LengthofstimulationminEditField
        function LengthofstimulationminEditFieldValueChanged(app, event)
            app.TotalNumberOfMinutes=app.LengthofstimulationminEditField.Value;
            app.MinutesLeft=app.TotalNumberOfMinutes;
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", condition ",app.Condition,", ",num2str(app.TotalNumberOfMinutes)," minutes");
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Enable=0;
            
            app.Task_Paused=0; %re-initialize Task_Paused
            app.PAUSEButton.Enable=1;
            
            %set some labels:
            app.CurrentStimIndicator.Text=app.Condition;
            app.Lamp.Color=[1 1 1];
            app.MinutesSentValue.Text=num2str(app.MinutesLeft);
            app.TimeStartedValue.Text=char(datetime('now','Format','HH:mm:ss')); %not exactly the same time as tic, but OK for the log file
            
            %indicate in log file that started stim, keep track of when we
            %started stim, and start the stim:
            fprintf(app.CallingApp.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'Started stim period');
            app.TimeStarted=tic; %start timer
            call_to_NIDAQ(app.CallingApp,app.Condition,app.Source_Signal,app.MinutesLeft); %this will play our 1min of created signal, x MinutesLeft
            
            app.Lamp.Color=[0 0 0];
            
            if ~app.Task_Paused %means we ran the whole flicker duration successfully
                fprintf(app.CallingApp.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'Completed task');
                app.CurrentStimIndicator.Text='Done';
                sound(generate_1s_sound(500,200000),200000); %will this be quick enough?
                msgbox('Done with session.','Done','help');
            else
                app.STARTButton.Enable=1;
            end
            
            app.PAUSEButton.Enable=0;
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            app.Task_Paused=1;
            call_to_NIDAQ(app.CallingApp,'stop');
            app.MinutesLeft=ceil(app.MinutesLeft-toc(app.TimeStarted)/60); %calculate the number of minutes left, based off of timer
            fprintf(app.CallingApp.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'stimulus aborted early; task paused'); %indicate that we paused the task
            app.CurrentStimIndicator.Text='Task paused...';
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            exitSubApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'RunTask';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            app.CurrentstimulusLabel = uilabel(app.GridLayout);
            app.CurrentstimulusLabel.Text = 'Current stimulus:';
            app.CurrentStimIndicator = uilabel(app.GridLayout);
            app.CurrentStimIndicator.Text = '';
            app.Lamp = uilamp(app.GridLayout);
            app.MinutessentLabel = uilabel(app.GridLayout);
            app.MinutessentLabel.Text = 'Minutes sent:';
            app.MinutesSentValue = uilabel(app.GridLayout);
            app.MinutesSentValue.Text = '';
            app.TimestartedLabel = uilabel(app.GridLayout);
            app.TimestartedLabel.Text = 'Time started:';
            app.TimeStartedValue = uilabel(app.GridLayout);
            app.TimeStartedValue.Text = '';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.PAUSEButton = uibutton(app.GridLayout, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Enable = 'off';
            app.PAUSEButton.Text = 'PAUSE';
            app.LengthofstimulationminLabel = uilabel(app.GridLayout);
            app.LengthofstimulationminLabel.Text = 'Length of stimulation (min):';
            app.LengthofstimulationminEditField = uieditfield(app.GridLayout, 'numeric');
            app.LengthofstimulationminEditField.ValueChangedFcn = createCallbackFcn(app, @LengthofstimulationminEditFieldValueChanged, true);
            app.LengthofstimulationminEditField.Enable = 'off';
            app.ConditionDropDownLabel = uilabel(app.GridLayout);
            app.ConditionDropDownLabel.Text = 'Condition:';
            app.ConditionDropDown = uidropdown(app.GridLayout);
            app.ConditionDropDown.Items = {'select', 'R-AV', '5.5Hz-AV', '40Hz-AV'};
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.ConditionDropDown.Enable = 'off';
            app.ConditionDropDown.Value = 'select';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunTask_FlickerDuration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end