classdef RunTask_FlickerMemory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow         matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        info               matlab.ui.control.Label
        Lamp               matlab.ui.control.Lamp
        STARTButton        matlab.ui.control.Button
        PAUSEButton        matlab.ui.control.Button
        CurrenttrialLabel  matlab.ui.control.Label
        Label              matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
        Block=[]
        Session_Number=0 %keeps track of which session we're on
        Trial_Number=0 %keeps track of which trial, within a given session, we're on
        Trial_Condition %condition of given trial
        Random_Trial_Number=0 %keeps track of which random condition trial we're on, overall
        Source_Signal
        Task_Paused=0 %whether we paused task
        TaskParameters
        PTParameters
        Trials
        Random_trial_number=0
        
    end
    
    methods (Access = private)
        
        function run_1_trial(app,typeOfTrial)
            %determine which signal to send:
            switch app.Trials.(typeOfTrial)(app.Trial_Number).stim_condition
                case 'Baseline'
                    signalData=app.Source_Signal.Baseline;
                case 'R-AV'
                    app.Random_Trial_Number=app.Random_Trial_Number+1;
                    signalData=app.Source_Signal.Rand{app.Random_Trial_Number};
                case '5.5Hz-AV'
                    signalData=app.Source_Signal.Hz5_5;
                case '40Hz-AV'
                    signalData=app.Source_Signal.Hz40;
            end
            
            %draw cross:
            draw_cross(app.PTParameters.key,app.PTParameters.Window,app.PTParameters.xCenter,app.PTParameters.yCenter,app.PTParameters.cross_coords,app.TaskParameters.fix_cross_duration.averageTime+(rand-app.TaskParameters.fix_cross_duration.randomVariation));
            
            %display a picture:
            call_to_NIDAQ(app.CallingApp,'picturePres'); %indicate in recording that we are presenting a picture
            [app.Trials.(typeOfTrial)(app.Trial_Number).imgStarttime,imagetexture]=display_image(app.Trials.(typeOfTrial)(app.Trial_Number).fullname,app.PTParameters.Window,app.PTParameters.xCenter,app.PTParameters.yCenter,app.PTParameters.screenXpixels,app.PTParameters.screenYpixels);
            
            %wait for subject response:
            [app.Trials.(typeOfTrial)(app.Trial_Number).rt,app.Trials.(typeOfTrial)(app.Trial_Number).response]=in_out_response(app.PTParameters.Window,app.PTParameters.key,app.Trials.(typeOfTrial)(app.Trial_Number).imgStarttime,app.TaskParameters.image_duration,app.TaskParameters.in_sound,app.TaskParameters.out_sound,app.TaskParameters.sample_rate);
            
            %erase image and free up memeory used by image since we've already copied it to buffer
            blankScreen(app.PTParameters.Window);
            Screen('Close', imagetexture);
            
            %PLAY 3S OF CONDITION
            app.Lamp.Color=[1 1 1]; %WHY DOESN'T THIS WORK?
            drawnow;
            fprintf(app.CallingApp.FileID,'\n%s\t%s\t%s\t%s',strjoin(string(round(clock)),'-'),string(app.Block),string(app.Trial_Number),app.Trials.(typeOfTrial)(app.Trial_Number).stim_condition);
            call_to_NIDAQ(app.CallingApp,app.Trials.(typeOfTrial)(app.Trial_Number).stim_condition,signalData);
            app.Lamp.Color=[0 0 0]; %NEED TO FIX THIS- LAMP DOESN'T STAY ON FOR LONG
            drawnow;
            
            %wait for ISI:
            WaitSecs(app.TaskParameters.stim_duration+app.TaskParameters.ISI); %wait for ISI duration
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask)
            app.CallingApp=FlickerMasterTask; %define the calling app
            
            %adjust window position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening RunTask sub-app'); %indicate in log file that we are starting the RunTask
            call_to_NIDAQ(app.CallingApp,'startRunTask'); %indicate in recording that we are starting the runTask
            
            %set some labels
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", ",app.CallingApp.TaskName,", ",app.CallingApp.Phase," phase");
            
            %initialize flicker memory task- i.e. get parameters of task and details about trials:
            [app.TaskParameters,app.Trials]=initialize_FlickerMemoryTask();
            
            %create flicker source signal for the task:
            app.Source_Signal.Hz5_5=produceSignals(app.TaskParameters.stim_duration,5.5,0,app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate);
            app.Source_Signal.Hz40=produceSignals(app.TaskParameters.stim_duration,40,0,app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate);
            for i=1:(sum(strcmp([app.Trials.study.stim_condition],"R-AV"))+sum(strcmp([app.Trials.practice.stim_condition],"R-AV")))
                app.Source_Signal.Rand{i}=produceSignals(app.TaskParameters.stim_duration,40,1,app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate);
            end
            app.Source_Signal.Baseline=zeros(3*app.CallingApp.NIDAQSampleRate,2);
            
            %initialize psych toolbox:
            app.PTParameters=initialize_psychToolbox();
            
            %add parameters to saved mat variable:
            temp=app.CallingApp.MatFileObj.ExpDetails; %fetch the structure
            temp.Source_Signal=app.Source_Signal; %add the Source_Signal
            temp.Trials=app.Trials;
            temp.TaskParameters=app.TaskParameters;
            temp.PTParameters=app.PTParameters;
            app.CallingApp.MatFileObj.ExpDetails=temp; %replace the structure in mat file with appended structure- good to save this first pass
            
            figure(app.CallingApp.MainWindow);
            figure(app.MainWindow);
            
            %enable buttons:
            app.STARTButton.Enable=1;
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            quitAnswer = uiconfirm(app.MainWindow,'Are you sure you want to quit?','Confirm close','Options',{'Yes','No'}); %ask user if sure they want to quit
            if strcmp(quitAnswer,'Yes') %if user answer was 'Yes', do the following:
                call_to_NIDAQ(app.CallingApp,'end'); %indicate in recording that we finished running this sub-app
                app.STARTButton.Enable=0;
                %save behavioral data so far:
                temp=app.CallingApp.MatFileObj.ExpDetails; %fetch the structure
                temp.Trials=app.Trials;
                app.CallingApp.MatFileObj.ExpDetails=temp; %replace the structure in mat file with appended structure- good to save this first pass
                Screen(app.PTParameters.Window,'Close');
                Priority(0);
                changeMainAppState(app.CallingApp,1);
                fprintf(app.CallingApp.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'Exiting from sub-app');
                figure(app.CallingApp.MainWindow); %select main window again
                delete(app) %WAY TO CLEANLY STOP ALL PROCESSES AND EXIT APP?
            end
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Enable=0;
            app.PAUSEButton.Enable=1; %WHY DOES IT NOT WORK/ WHY DO WE NEED TO PUT A PAUSE HERE?
            app.Task_Paused=0;
            drawnow;
            
            if isempty(app.Block) %means we are begining task
                app.Block=0;
                display_instructions(app.PTParameters.Window,app.TaskParameters.font_size,app.TaskParameters.initial_instruct); %show instructions for study phase
                display_instructions(app.PTParameters.Window,app.TaskParameters.font_size,app.TaskParameters.practice_instruct); %show instructions for practice trials
                blankScreen(app.PTParameters.Window);
            end
            
            if app.Block==0 %means we're in practice block
                for i=app.Trial_Number:length(app.Trials.practice)-1
                    drawnow;
                    if ~app.Task_Paused
                        app.Trial_Number=app.Trial_Number+1;
                        run_1_trial(app,'practice');
                    else
                        break;
                    end
                end
                if ~app.Task_Paused && app.Trial_Number==length(app.Trials.practice) %means we successfully completed the practice block, moving on to 1st study block
                    %save behavioral data so far:
                    temp=app.CallingApp.MatFileObj.ExpDetails; %fetch the structure
                    temp.Trials=app.Trials;
                    app.CallingApp.MatFileObj.ExpDetails=temp; %replace the structure in mat file with appended structure- good to save this first pass
                    
                    app.Block=1;
                    app.Trial_Number=0;
                    display_instructions(app.PTParameters.Window,app.TaskParameters.font_size,app.TaskParameters.study_instruct);
                    blankScreen(app.PTParameters.Window);
                end
            end
            
            if app.Block==1 %means we are in study phase
                for i=app.Trial_Number:length(app.Trials.study)-1
                    drawnow;
                    if ~app.Task_Paused
                        app.Trial_Number=app.Trial_Number+1;
                        if app.Trial_Number~=1 && mod(app.Trial_Number-1,length(app.Trials.study)/4)==0 %if we are at begining of new block, display instructions
                            %save behavioral data so far:
                            temp=app.CallingApp.MatFileObj.ExpDetails; %fetch the structure
                            temp.Trials=app.Trials;
                            app.CallingApp.MatFileObj.ExpDetails=temp; %replace the structure in mat file with appended structure- good to save this first pass
                    
                            display_instructions(app.PTParameters.Window,app.TaskParameters.font_size,app.TaskParameters.block_instruct);
                            blankScreen(Window);
                        end
                        run_1_trial(app,'study');
                    else
                        break;
                    end
                end
                if ~app.Task_Paused && app.Trial_Number==length(app.Trials.practice) %means we went through all the study trials
                    %save behavioral data so far:
                    temp=app.CallingApp.MatFileObj.ExpDetails; %fetch the structure
                    temp.Trials=app.Trials;
                    app.CallingApp.MatFileObj.ExpDetails=temp; %replace the structure in mat file with appended structure- good to save this first pass
                end
            end
            
            app.PAUSEButton.Enable=0;
            app.STARTButton.Enable=1;
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            app.Task_Paused=1;
            call_to_NIDAQ(app.CallingApp,'stop'); %first, stop whatever signal is playing on the NIDAQ
            fprintf(app.CallingApp.FileID,'\t%s','stimulus aborted early; task paused');
            app.Lamp.Color=[0 0 0];
            app.PAUSEButton.Enable=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'FlickerMemory_StudyPhase';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            app.Lamp = uilamp(app.GridLayout);
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.PAUSEButton = uibutton(app.GridLayout, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Enable = 'off';
            app.PAUSEButton.Text = 'PAUSE';
            app.CurrenttrialLabel = uilabel(app.GridLayout);
            app.CurrenttrialLabel.Text = 'Current trial:';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunTask_FlickerMemory(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end