classdef RunTask_FlickerMemoryTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow         matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        info               matlab.ui.control.Label
        CurrenttrialLabel  matlab.ui.control.Label
        Label              matlab.ui.control.Label
        STARTButton        matlab.ui.control.Button
        PAUSEButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp
        TaskParameters
        PTParameters
        Trial_Number=0
        Trials
        Task_Paused=0
    end
    
    methods (Access = private)
        
        function run_1_trial(app)
            %draw cross:
            draw_cross(app.PTParameters.key,app.PTParameters.Window,app.PTParameters.xCenter,app.PTParameters.yCenter,app.PTParameters.cross_coords,app.TaskParameters.fix_cross_duration.averageTime+(rand-app.TaskParameters.fix_cross_duration.randomVariation));
            
            %display a picture:
            call_to_NIDAQ(app.CallingApp,'picturePres'); %indicate in recording that we are presenting a picture
            [app.Trials.one_day_test(app.Trial_Number).rt,app.Trials.one_day_test(app.Trial_Number).response]=sureOld_sureNew_response(app.PTParameters.Window,app.Trials.one_day_test(app.Trial_Number).fullname,app.PTParameters.xCenter,app.PTParameters.yCenter,app.PTParameters.screenXpixels,app.PTParameters.screenYpixels,app.PTParameters.key,app.TaskParameters.test_image_duration,app.TaskParameters.font_size,app.TaskParameters.test_trial_instruction);
            
            fprintf(app.CallingApp.FileID,'\n%s\t%s',strjoin(string(round(clock)),'-'),string(app.Trial_Number));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask)
            app.CallingApp=FlickerMasterTask;
            
            %adjust window position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening RunTask sub-app'); %indicate in log file that we are starting the RunTask
            call_to_NIDAQ(app.CallingApp,'startRunTask'); %indicate in recording that we are starting the runTask
            
            %set some labels
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", ",app.CallingApp.TaskName,", ",app.CallingApp.Phase," phase");
            
            %initialize flicker memory task- i.e. get parameters of task and details about trials:
            app.TaskParameters=set_PARAMETERS();
            
            %initialize psych toolbox:
            app.PTParameters=initialize_psychToolbox();
            
            %fetch list of test images:
            temp=app.CallingApp.MatFileObj.ExpDetails;
            app.Trials.one_day_test=temp.Trials.one_day_test;
            
            figure(app.CallingApp.MainWindow);
            figure(app.MainWindow);
            
            %enable buttons:
            app.STARTButton.Enable=1;
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            quitAnswer = uiconfirm(app.MainWindow,'Are you sure you want to quit?','Confirm close','Options',{'Yes','No'}); %ask user if sure they want to quit
            if strcmp(quitAnswer,'Yes') %if user answer was 'Yes', do the following:
                call_to_NIDAQ(app.CallingApp,'end'); %indicate in recording that we finished running this sub-app
                app.STARTButton.Enable=0;
                %save behavioral data so far:
                temp=app.CallingApp.MatFileObj.ExpDetails; %fetch the structure
                temp.Trials.one_day_test=app.Trials.one_day_test;
                app.CallingApp.MatFileObj.ExpDetails=temp; %replace the structure in mat file with appended structure- good to save this first pass
                Screen(app.PTParameters.Window,'Close');
                Priority(0);
                changeMainAppState(app.CallingApp,1);
                fprintf(app.CallingApp.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'Exiting from sub-app');
                figure(app.CallingApp.MainWindow); %select main window again
                delete(app) %WAY TO CLEANLY STOP ALL PROCESSES AND EXIT APP?
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Enable=0;
            app.PAUSEButton.Enable=1; %WHY DOES IT NOT WORK/ WHY DO WE NEED TO PUT A PAUSE HERE?
            app.Task_Paused=0;
            drawnow;
            
            if app.Trial_Number==0
                display_instructions(app.PTParameters.Window,app.TaskParameters.font_size,app.TaskParameters.test_initial_instruct); %show instructions for practice trials
                blankScreen(app.PTParameters.Window);
            end
            
            for i=app.Trial_Number:length(app.Trials.one_day_test)-1
                drawnow;
                if ~app.Task_Paused
                    app.Trial_Number=app.Trial_Number+1;
                    if app.Trial_Number~=1 && mod(app.Trial_Number-1,length(app.Trials.one_day_test)/4)==0 %if we are at begining of new block, display instructions
                        %save behavioral data so far:
                        temp=app.CallingApp.MatFileObj.ExpDetails; %fetch the structure
                        temp.Trials.one_day_test=app.Trials.one_day_test;
                        app.CallingApp.MatFileObj.ExpDetails=temp; %replace the structure in mat file with appended structure- good to save this first pass
                        
                        display_instructions(app.PTParameters.Window,app.TaskParameters.font_size,app.TaskParameters.block_instruct);
                        blankScreen(Window);
                    end
                    run_1_trial(app);
                else
                    break;
                end
            end
            
            app.PAUSEButton.Enable=0;
            app.STARTButton.Enable=1;
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            app.Task_Paused=1;
            call_to_NIDAQ(app.CallingApp,'stop'); %first, stop whatever signal is playing on the NIDAQ
            fprintf(app.CallingApp.FileID,'\t%s','task paused');
            app.PAUSEButton.Enable=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'UI Figure';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            app.CurrenttrialLabel = uilabel(app.GridLayout);
            app.CurrenttrialLabel.Text = 'Current trial:';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.PAUSEButton = uibutton(app.GridLayout, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Enable = 'off';
            app.PAUSEButton.Text = 'PAUSE';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunTask_FlickerMemoryTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end