classdef RunTask_PulseEvokedPotentials < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow             matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        info                   matlab.ui.control.Label
        CurrentstimulusLabel   matlab.ui.control.Label
        CurrentStimIndicator   matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        TrialNumberLabel       matlab.ui.control.Label
        STARTButton            matlab.ui.control.Button
        PAUSEButton            matlab.ui.control.Button
        TrialNumberValueLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
        Source_Signal
        Trial_Number=0
        Trial_Condition
        Task_Paused=0 %to keep track of whether task was paused
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask)
            app.CallingApp=FlickerMasterTask; %define the calling app
            
            %adjust gui position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening RunTask sub-app'); %indicate in log file that we are testing for comfort
            call_to_NIDAQ(app.CallingApp,'startRunTask'); %indicate in recording that we are starting the duration task
            
            one_sec_signal=producePulse(app.CallingApp.PulseDuration,app.CallingApp.SoundFreq,app.CallingApp.NIDAQSampleRate);
            app.Source_Signal=produceMultiplePulses('neurophys_experiment',one_sec_signal,app.CallingApp.InterTrialInterval,app.CallingApp.NumberOfTrials_perCondition,app.CallingApp.NIDAQSampleRate);
            
            %add additional information, including Source_Signal, to the structure in the mat file we saved
            temp=app.CallingApp.MatFileObj.ExpDetails; %fetch the structure
            temp.Source_Signal=struct(); %add the Source_Signal
            temp.Source_Signal.trials_vector=app.Source_Signal.trials_vector;
            temp.Source_Signal.added_intervals=app.Source_Signal.added_intervals;
            app.CallingApp.MatFileObj.ExpDetails=temp; %replace the structure in mat file with appended structure
            
            %set some labels
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", sensory evoked potentials task");
            
            %write begining of task info in log file:
            fprintf(app.CallingApp.FileID,'\n%s\t%s\t%s\t%s\t%s','Time','Trial_nber','Condition','Comments'); %keep track of comments
            
            app.STARTButton.Enable=1;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Enable=0;
            app.PAUSEButton.Enable=1;
            
            %figure out the vector of trials we need to play:
            if ~app.Task_Paused
                trials=1:length(app.Source_Signal.signals);
            elseif app.Task_Paused
                if app.Trial_Number<=10 %if we were within the first 10 trials, go back to first trial
                    trials=1:length(app.Source_Signal.signals);
                else %else, go back 10 trials
                    trials=app.Trial_Number-10:length(app.Source_Signal.signals);
                end
                app.Task_Paused=0; %re-initialize
            end
            
            for trial=trials
                if ~app.Task_Paused %it task was not paused, can play next trial
                    
                    app.Trial_Number=trial;
                    app.Trial_Condition=app.Source_Signal.trials_vector(app.Trial_Number);
                    app.CurrentStimIndicator.Text=app.Trial_Condition;
                    app.TrialNumberValueLabel.Text=strcat(num2str(app.Trial_Number)," (out of ",num2str(max(trials)),")");
                    
                    app.Lamp.Color=[1 1 1];
                    fprintf(app.CallingApp.FileID,'\n%s\t%s\t%s',strjoin(string(round(clock)),'-'),num2str(app.Trial_Number),app.Trial_Condition);
                    call_to_NIDAQ(app.CallingApp,app.Trial_Condition,app.Source_Signal.signals{app.Trial_Number});
                    app.Lamp.Color=[0 0 0];
                    
                elseif app.Task_Paused
                    break;
                end
            end
            
            if ~app.Task_Paused %if task was not paused, means we went through the whole task successfully
                fprintf(app.CallingApp.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'Completed task');
                app.TrialNumberValueLabel.Text='Done';
                app.CurrentStimIndicator.Text='Done';
                sound(generate_sound(500,200000),200000); %will this be quick enough?
                msgbox('Done with session.','Done','help');
                app.PAUSEButton.Enable=0;
            else
                app.STARTButton.Enable=1; %re-activate START button
            end
            
            app.PAUSEButton.Enable=0;
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            app.Task_Paused=1;
            call_to_NIDAQ(app.CallingApp,'stop');
            fprintf(app.CallingApp.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'stimulus aborted early; task paused');
            app.Lamp.Color=[0 0 0];
            app.CurrentStimIndicator.Text='Task paused...';
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            exitSubApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'RunTask';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            app.CurrentstimulusLabel = uilabel(app.GridLayout);
            app.CurrentstimulusLabel.Text = 'Current stimulus:';
            app.CurrentStimIndicator = uilabel(app.GridLayout);
            app.CurrentStimIndicator.Text = '';
            app.Lamp = uilamp(app.GridLayout);
            app.TrialNumberLabel = uilabel(app.GridLayout);
            app.TrialNumberLabel.Text = 'Trial Number:';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.PAUSEButton = uibutton(app.GridLayout, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Enable = 'off';
            app.PAUSEButton.Text = 'PAUSE';
            app.TrialNumberValueLabel = uilabel(app.GridLayout);
            app.TrialNumberValueLabel.Text = '';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunTask_PulseEvokedPotentials(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end