classdef SafetyTesting_Flicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow        matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        ModalityLabel     matlab.ui.control.Label
        FrequencyLabel    matlab.ui.control.Label
        VisualLabel       matlab.ui.control.Label
        AudioVisualLabel  matlab.ui.control.Label
        AudioLabel        matlab.ui.control.Label
        RandLabel         matlab.ui.control.Label
        HzLabel           matlab.ui.control.Label
        HzLabel_2         matlab.ui.control.Label
        HzLabel_3         matlab.ui.control.Label
        Button_1          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_9          matlab.ui.control.Button
        Button_10         matlab.ui.control.Button
        Button_11         matlab.ui.control.Button
        Button_12         matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        info              matlab.ui.control.Label
        Label             matlab.ui.control.Label
    end

    properties (Access = public)
        CallingApp
        Source_Signal
        Buttons_to_Enable %button numbers that correspond to conditions we will test (i.e. in this case, all 12 of them)
    end
    
    methods (Access = private)
        
        function changeStateAllConditionButtons(app,on_off)
            app.StopButton.Enable=~on_off; %change stop button to opposite state of conditions' buttons
            for buttonNumber=append(repmat("Button_",1,length(app.Buttons_to_Enable)),app.Buttons_to_Enable) %for all buttons that we need, change state
                app.(buttonNumber).Enable=on_off;
            end
        end
        
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask, buttons_to_enable)
            app.CallingApp=FlickerMasterTask;
            
            %adjust window position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening SafetyTesting sub-app'); %indicate in log file that we are testing for safety
            call_to_NIDAQ(app.CallingApp,'startSafetyTest'); %indicate in recording that we are starting safetyTesting
            
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", testing for safety"); %label information about this sub-app
            
            app.Source_Signal=produceExperimentSignals('safety_testing',app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate);
            
            fprintf(app.CallingApp.FileID,'\n%s\t%s\t%s','Time','Type of stim','Comments');
            
            app.Buttons_to_Enable=buttons_to_enable;
            
            %now that initialized sub-task, can enable buttons
            changeStateAllConditionButtons(app,1);
        end

        % Button pushed function: Button_1, Button_10, Button_11, 
        % Button_12, Button_2, Button_3, Button_4, Button_5, 
        % Button_6, Button_7, Button_8, Button_9
        function gridButtonPushed(app, event)
            changeStateAllConditionButtons(app,0); %disable buttons
            typeStimulation=event.Source.Text; %get type of stimulation that was chosen
            
            %determine which signal to send to the NIDAQ:
            typeStimulation_dissected=strsplit(typeStimulation,'-');
            switch typeStimulation_dissected{1}
                case 'R'
                    signalData=app.Source_Signal.Rand;
                case '5.5Hz'
                    signalData=app.Source_Signal.Hz5_5;
                case '40Hz'
                    signalData=app.Source_Signal.Hz40;
                case '80Hz'
                    signalData=app.Source_Signal.Hz80;
            end
            switch typeStimulation_dissected{2}
                case 'V'
                    signalData(:,2)=0;
                case 'AV'
                    %nothing to do
                case 'A'
                    signalData(:,1)=0;
            end
            
            app.Label.Text=['Playing ',typeStimulation]; %label which stimulation we're playing
            fprintf(app.CallingApp.FileID,'\n%s\t%s',strjoin(string(round(clock)),'-'),typeStimulation); %indicate in log file we're starting given stimulation
            call_to_NIDAQ(app.CallingApp,typeStimulation,signalData); %send signal to NIDAQ
            
            if ~strcmp(app.Label.Text,'Stimulus aborted.') %if the stimulation was not aborted, clear the label
                app.Label.Text='';
            end
            
            event.Source.BackgroundColor=[1 1 0]; %label the button that was just pressed in yellow, so user can keep track of conditions that were already tested
            changeStateAllConditionButtons(app,1); %enable buttons again
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            call_to_NIDAQ(app.CallingApp,'stop'); %stop playing signal
            fprintf(app.CallingApp.FileID,'\t%s','stimulus aborted early'); %indicate we aborted signal, in log file
            app.Label.Text='Stimulus aborted.'; %indicate that we aborted signal
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            exitSubApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'SafetyTesting';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.ModalityLabel = uilabel(app.GridLayout);
            app.ModalityLabel.Text = 'Modality';
            app.FrequencyLabel = uilabel(app.GridLayout);
            app.FrequencyLabel.Text = {'F'; 'r'; 'e'; 'q'; 'u'; 'e'; 'n'; 'c'; 'y'};
            app.VisualLabel = uilabel(app.GridLayout);
            app.VisualLabel.Text = 'Visual';
            app.AudioVisualLabel = uilabel(app.GridLayout);
            app.AudioVisualLabel.Text = {'Audio-'; 'Visual'};
            app.AudioLabel = uilabel(app.GridLayout);
            app.AudioLabel.Text = 'Audio';
            app.RandLabel = uilabel(app.GridLayout);
            app.RandLabel.Text = 'Rand';
            app.HzLabel = uilabel(app.GridLayout);
            app.HzLabel.Text = '5.5Hz';
            app.HzLabel_2 = uilabel(app.GridLayout);
            app.HzLabel_2.Text = '40Hz';
            app.HzLabel_3 = uilabel(app.GridLayout);
            app.HzLabel_3.Text = '80Hz';
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_1.Enable = 'off';
            app.Button_1.Text = 'R-V';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = 'R-AV';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = 'R-A';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_4.Enable = 'off';
            app.Button_4.Text = '5.5Hz-V';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '5.5Hz-AV';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_6.Enable = 'off';
            app.Button_6.Text = '5.5Hz-A';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_7.Enable = 'off';
            app.Button_7.Text = '40Hz-V';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_8.Enable = 'off';
            app.Button_8.Text = '40Hz-AV';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_9.Enable = 'off';
            app.Button_9.Text = '40Hz-A';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_10.Enable = 'off';
            app.Button_10.Text = '80Hz-V';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_11.Enable = 'off';
            app.Button_11.Text = '80Hz-AV';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_12.Enable = 'off';
            app.Button_12.Text = '80Hz-A';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SafetyTesting_Flicker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end