classdef SafetyTesting_FlickerFrequency < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow      matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        StopButton      matlab.ui.control.Button
        info            matlab.ui.control.Label
        Label           matlab.ui.control.Label
        Button_1        matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        Button_4        matlab.ui.control.Button
        Button_5        matlab.ui.control.Button
        Button_6        matlab.ui.control.Button
        Button_7        matlab.ui.control.Button
        Button_8        matlab.ui.control.Button
        Button_9        matlab.ui.control.Button
        Button_10       matlab.ui.control.Button
        Button_11       matlab.ui.control.Button
        Button_12       matlab.ui.control.Button
        Button_13       matlab.ui.control.Button
        Button_14       matlab.ui.control.Button
        Button_15       matlab.ui.control.Button
        Button_16       matlab.ui.control.Button
        Button_17       matlab.ui.control.Button
        Button_18       matlab.ui.control.Button
        Button_19       matlab.ui.control.Button
        Button_20       matlab.ui.control.Button
        Button_21       matlab.ui.control.Button
        Button_22       matlab.ui.control.Button
        Button_23       matlab.ui.control.Button
        Button_24       matlab.ui.control.Button
        Button_25       matlab.ui.control.Button
        Button_26       matlab.ui.control.Button
        Button_27       matlab.ui.control.Button
        Button_28       matlab.ui.control.Button
        Button_29       matlab.ui.control.Button
        Button_30       matlab.ui.control.Button
        modality_label  matlab.ui.control.Label
    end

    properties (Access = public)
        CallingApp
        Source_Signal
        Buttons_to_Enable %button numbers that correspond to conditions we will test (i.e. in this case, all 12 of them)
        Last_button_pushed %will always start with Button_1
        Stop_button_pushed=0;
    end
    
    methods (Access = private)
        
        function changeStateAllConditionButtons(app,on_off)
            app.StopButton.Enable=~on_off; %change stop button to opposite state of conditions' buttons
            for buttonNumber=append(repmat("Button_",1,length(app.Buttons_to_Enable)),app.Buttons_to_Enable) %for all buttons that we need, change state
                app.(buttonNumber).Enable=on_off;
            end
        end
        
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask)
            app.CallingApp=FlickerMasterTask;
            
            %adjust window position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening SafetyTesting sub-app'); %indicate in log file that we are testing for safety
            call_to_NIDAQ(app.CallingApp,'startSafetyTest'); %indicate in recording that we are starting safetyTesting
            
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", testing for safety"); %label information about this sub-app
            app.modality_label.Text=['Modality: ' app.CallingApp.TaskModality];
            
            %create all the signals we'll need
            frequencies_tested=app.CallingApp.TaskFrequencies;
            frequencies_tested(ismember(frequencies_tested,'R'))='-1'; %convert for function below
            frequencies_tested=[frequencies_tested; '0']; %add baseline
            frequencies_tested=regexprep(frequencies_tested,'Hz','');
            frequencies_tested=str2num(char(frequencies_tested))';
            app.Source_Signal=produceExperimentSignals_FlickerFrequency(1,app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate,frequencies_tested,{app.CallingApp.TaskModality(1)},[3 Inf],10,2,10,6,'distributed_random');

            fprintf(app.CallingApp.FileID,'\n%s\t%s\t%s','Time','Type of stim','Comments');

            app.Buttons_to_Enable=1:length(app.CallingApp.TaskFrequencies);
            app.Buttons_to_Enable=arrayfun(@(x) {num2str(x)},app.Buttons_to_Enable);
            button_number=0;
            for buttonNumber=append(repmat("Button_",1,length(app.Buttons_to_Enable)),app.Buttons_to_Enable) %for all buttons that we need, show them
                button_number=button_number+1;
                app.(buttonNumber).Text=strcat(app.CallingApp.TaskFrequencies(button_number),'-',app.CallingApp.TaskModality(1));
                app.(buttonNumber).Visible=1;
            end
            
            %now that initialized sub-task, can enable buttons
            app.Button_1.Enable=1;
            app.StopButton.Enable=1;
        end

        % Button pushed function: Button_1, Button_10, Button_11, 
        % Button_12, Button_13, Button_14, Button_15, Button_16, 
        % Button_17, Button_18, Button_19, Button_2, Button_20, 
        % Button_21, Button_22, Button_23, Button_24, Button_25, 
        % Button_26, Button_27, Button_28, Button_29, Button_3, 
        % Button_30, Button_4, Button_5, Button_6, Button_7, 
        % Button_8, Button_9
        function gridButtonPushed(app, event)
            changeStateAllConditionButtons(app,0); %disable buttons
            app.Stop_button_pushed=0; %reset record of stop button pushed, if it was pushed
            
            %figure out the number of the button that was last pressed:
            typeStimulation=event.Source.Text; %get type of stimulation that was chosen
            for temp=1:length(app.Buttons_to_Enable)
                if strcmp(app.(['Button_' num2str(temp)]).Text,typeStimulation)
                    break;
                end
            end
            app.Last_button_pushed=temp;
            
            for i=app.Last_button_pushed:length(app.Buttons_to_Enable) %for button that was just pressed until all buttons
                if ~app.Stop_button_pushed
                    app.(['Button_' num2str(i)]).FontColor=[1 0 0];
                    %determine which signal to send to the NIDAQ:
                    typeStimulation=app.(['Button_' num2str(i)]).Text; %updates every loop iteration
                    typeStimulation_dissected=strsplit(typeStimulation,'-');
                    if strcmp(typeStimulation_dissected(1),'R') %if this is a random flicker trial
                        signalData=app.Source_Signal.Rand{1};
                    else
                        temp=regexprep(typeStimulation_dissected{1},'Hz','');
                        temp=regexprep(temp,'\.','_'); %in case there is a dot
                        signalData=app.Source_Signal.(strcat('Hz',temp));
                    end
                    switch typeStimulation_dissected{2}
                        case 'V'
                            signalData(:,2)=0;
                        case 'AV'
                            %nothing to do
                        case 'A'
                            signalData(:,1)=0;
                    end
                    
                    %add jittered no stim interval to signalData:
                    signalData=[signalData;zeros(app.Source_Signal.added_intervals{i},2)];
                    
                    app.Label.Text=['Playing ',typeStimulation]; %label which stimulation we're playing
                    fprintf(app.CallingApp.FileID,'\n%s\t%s',strjoin(string(round(clock)),'-'),typeStimulation); %indicate in log file we're starting given stimulation
                    call_to_NIDAQ(app.CallingApp,typeStimulation,signalData); %send signal to NIDAQ
                    
                    if ~strcmp(app.Label.Text,'Stimulus aborted.') %if the stimulation was not aborted, clear the label
                        app.Label.Text='';
                    end
                    
                    app.(['Button_' num2str(i)]).FontColor=[0 0 0];
                    app.(['Button_' num2str(i)]).BackgroundColor=[1 1 0]; %label the button that was just pressed in yellow, so user can keep track of conditions that were already tested
                end
            end

            changeStateAllConditionButtons(app,1); %enable buttons again
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            call_to_NIDAQ(app.CallingApp,'stop'); %stop playing signal
            app.Stop_button_pushed=1;
            fprintf(app.CallingApp.FileID,'\t%s','stimulus aborted early'); %indicate we aborted signal, in log file
            app.Label.Text='Stimulus aborted.'; %indicate that we aborted signal
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            exitSubApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'SafetyTesting';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_1.Enable = 'off';
            app.Button_1.Visible = 'off';
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Visible = 'off';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Visible = 'off';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_4.Enable = 'off';
            app.Button_4.Visible = 'off';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_5.Enable = 'off';
            app.Button_5.Visible = 'off';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_6.Enable = 'off';
            app.Button_6.Visible = 'off';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_7.Enable = 'off';
            app.Button_7.Visible = 'off';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_8.Enable = 'off';
            app.Button_8.Visible = 'off';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_9.Enable = 'off';
            app.Button_9.Visible = 'off';
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_10.Enable = 'off';
            app.Button_10.Visible = 'off';
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_11.Enable = 'off';
            app.Button_11.Visible = 'off';
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_12.Enable = 'off';
            app.Button_12.Visible = 'off';
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.GridLayout, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_13.Enable = 'off';
            app.Button_13.Visible = 'off';
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.GridLayout, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_14.Enable = 'off';
            app.Button_14.Visible = 'off';
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.GridLayout, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_15.Enable = 'off';
            app.Button_15.Visible = 'off';
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.GridLayout, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_16.Enable = 'off';
            app.Button_16.Visible = 'off';
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.GridLayout, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_17.Enable = 'off';
            app.Button_17.Visible = 'off';
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.GridLayout, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_18.Enable = 'off';
            app.Button_18.Visible = 'off';
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.GridLayout, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_19.Enable = 'off';
            app.Button_19.Visible = 'off';
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.GridLayout, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_20.Enable = 'off';
            app.Button_20.Visible = 'off';
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.GridLayout, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_21.Enable = 'off';
            app.Button_21.Visible = 'off';
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.GridLayout, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_22.Enable = 'off';
            app.Button_22.Visible = 'off';
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.GridLayout, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_23.Enable = 'off';
            app.Button_23.Visible = 'off';
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.GridLayout, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_24.Enable = 'off';
            app.Button_24.Visible = 'off';
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.GridLayout, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_25.Enable = 'off';
            app.Button_25.Visible = 'off';
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.GridLayout, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_26.Enable = 'off';
            app.Button_26.Visible = 'off';
            app.Button_26.Text = '';
            app.Button_27 = uibutton(app.GridLayout, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_27.Enable = 'off';
            app.Button_27.Visible = 'off';
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.GridLayout, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_28.Enable = 'off';
            app.Button_28.Visible = 'off';
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.GridLayout, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_29.Enable = 'off';
            app.Button_29.Visible = 'off';
            app.Button_29.Text = '';
            app.Button_30 = uibutton(app.GridLayout, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_30.Enable = 'off';
            app.Button_30.Visible = 'off';
            app.Button_30.Text = '';
            app.modality_label = uilabel(app.GridLayout);
            app.modality_label.Text = '';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SafetyTesting_FlickerFrequency(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end