classdef SafetyTesting_Pulse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow     matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        ModalityLabel  matlab.ui.control.Label
        Button_1       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        StopButton     matlab.ui.control.Button
        info           matlab.ui.control.Label
        Label          matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
        Source_Signal
        StopButtonPressed
    end
    
    methods (Access = private)
        
        function changeStateAllConditionButtons(app,on_off)
            app.StopButton.Enable=~on_off;
            for buttonNumber=append(repmat("Button_",1,3),{'1','2','3'})
                app.(buttonNumber).Enable=on_off;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask)
            app.CallingApp=FlickerMasterTask;
            
            %adjust gui position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening SafetyTesting sub-app'); %indicate in log file that we are testing for comfort
            call_to_NIDAQ(app.CallingApp,'startSafetyTest'); %indicate in recording that we are starting safetyTesting
            
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", testing for safety"); %label information about this sub-app
            
            one_sec_signal=producePulse(app.CallingApp.PulseDuration,app.CallingApp.SoundFreq,app.CallingApp.NIDAQSampleRate);
            app.Source_Signal=produceMultiplePulses('safety_testing',one_sec_signal,app.CallingApp.InterTrialInterval,app.CallingApp.NumberOfTrials_perCondition,app.CallingApp.NIDAQSampleRate);
            
            fprintf(app.CallingApp.FileID,'\n%s\t%s\t%s','Time','Type of stim','Comments');
            
            changeStateAllConditionButtons(app,1);
        end

        % Button pushed function: Button_1, Button_2, Button_3
        function gridButtonPushed(app, event)
            changeStateAllConditionButtons(app,0);
            app.StopButtonPressed=0;
            typeStimulation=event.Source.Text;
            fprintf(app.CallingApp.FileID,'\n%s\t%s',strjoin(string(round(clock)),'-'),typeStimulation);
            
            app.Label.Text=['Playing ',typeStimulation];
            for i=1:10
                if ~app.StopButtonPressed
                    call_to_NIDAQ(app.CallingApp,typeStimulation,app.Source_Signal.(typeStimulation){i});
                elseif app.StopButtonPressed
                    break;
                end
            end
            
            if ~strcmp(app.Label.Text,'Stimulus aborted.')
                app.Label.Text='';
            end
            
            changeStateAllConditionButtons(app,1);
            event.Source.BackgroundColor=[1 1 0];
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButtonPressed=1;
            call_to_NIDAQ(app.CallingApp,'stop');
            fprintf(app.CallingApp.FileID,'\t%s','stimulus aborted early');
            app.Label.Text='Stimulus aborted.';
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            exitSubApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'SafetyTesting';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.ModalityLabel = uilabel(app.GridLayout);
            app.ModalityLabel.Text = 'Modality';
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_1.Enable = 'off';
            app.Button_1.Text = 'V';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = 'AV';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @gridButtonPushed, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = 'A';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SafetyTesting_Pulse(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end